classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Switch                     matlab.ui.control.Switch
        ManualMatrixControlsPanel  matlab.ui.container.Panel
        MartixField44              matlab.ui.control.NumericEditField
        MartixField33              matlab.ui.control.NumericEditField
        MartixField34              matlab.ui.control.NumericEditField
        MartixField24              matlab.ui.control.NumericEditField
        MartixField43              matlab.ui.control.NumericEditField
        MartixField23              matlab.ui.control.NumericEditField
        MartixField14              matlab.ui.control.NumericEditField
        MartixField12              matlab.ui.control.NumericEditField
        MartixField22              matlab.ui.control.NumericEditField
        MartixField32              matlab.ui.control.NumericEditField
        MartixField13              matlab.ui.control.NumericEditField
        MartixField11              matlab.ui.control.NumericEditField
        MartixField42              matlab.ui.control.NumericEditField
        MartixField41              matlab.ui.control.NumericEditField
        MartixField31              matlab.ui.control.NumericEditField
        MartixField21              matlab.ui.control.NumericEditField
        ControlsPanel              matlab.ui.container.Panel
        ChangeREditField           matlab.ui.control.NumericEditField
        ChangeREditFieldLabel      matlab.ui.control.Label
        ChangeThetaEditField       matlab.ui.control.NumericEditField
        ChangeThetaEditFieldLabel  matlab.ui.control.Label
        ChangePhiEditField         matlab.ui.control.NumericEditField
        ChangePhiEditFieldLabel    matlab.ui.control.Label
        ThetaSlider                matlab.ui.control.Slider
        ThetaSliderLabel           matlab.ui.control.Label
        RSlider                    matlab.ui.control.Slider
        RSliderLabel               matlab.ui.control.Label
        PhiSlider                  matlab.ui.control.Slider
        PhiSliderLabel             matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        shiftX % The shift in position for the X-Axis (Coil)
        shiftY % The shift in position for the Y-Axis (Coil)
        shiftZ % The shift in position for the Z-Axis (Coil)
        P % Description
        coilPlot % Change this to be the coil in attempt to streamline UIAxes updating speed
    end
    
    methods (Access = public)
        
        function [] = updateImage(app, RValue, PhiValue, ThetaValue)
           
            updateimage(app, RValue, PhiValue, ThetaValue); % calls function in AppFunctuons/updateimage.m

        end
    end
    
    methods (Access = private)
        
        function results = matrixValueChanged(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpathes();

            %addpath("Functions\");
            %addpath("Functions\*");
            
            load('CombinedMesh.mat', "P")
            app.P = P;
            
            %sets a few properties of the UIAxes
            grid(app.UIAxes,'on')
            app.UIAxes.XLim = [-0.2, 0.2];
            app.UIAxes.YLim = [-0.2, 0.2];
            app.UIAxes.ZLim = [-0.2, 0.2];

            %presets the edit fields to the correct values
            app.ChangeREditField.Value = app.RSlider.Value;
            app.ChangePhiEditField.Value = app.PhiSlider.Value;
            app.ChangeThetaEditField.Value = app.ThetaSlider.Value;

            %displays the initial image:
            updateImage(app, app.RSlider.Value, app.PhiSlider.Value, app.ThetaSlider.Value);
        end

        % Value changing function: RSlider
        function RSliderValueChanging(app, event)
            % gets the current value for R, makes R consistant across fields, and calls the function 
            % to update the image
            currentRValue = event.Value;
            app.ChangeREditField.Value = currentRValue;
            app.RSlider.Value = currentRValue;
            RSliderValueChanged(app, event);
        end

        % Value changing function: ThetaSlider
        function ThetaSliderValueChanging(app, event)
            % gets the current value for Theta, makes Theta consistant across fields, and calls the function 
            % to update the image
            currentThetaValue = event.Value;
            app.ChangeThetaEditField.Value = currentThetaValue;
            app.ThetaSlider.Value = currentThetaValue;
            RSliderValueChanged(app, event);
        end

        % Value changing function: PhiSlider
        function PhiSliderValueChanging(app, event)
            % gets the current value for Phi, makes Phi consistant across fields, and calls the function 
            % to update the image
            currentPhiValue = event.Value;
            app.ChangePhiEditField.Value = currentPhiValue;
            app.PhiSlider.Value = currentPhiValue;
            RSliderValueChanged(app, event);
        end

        % Value changed function: ChangeREditField
        function ChangeREditFieldValueChanged(app, event)
            % updates the value for R on the slider and calls the slider's
            % function to update the image
            value = app.ChangeREditField.Value;
            
            % Verify that the number is not outside of the range of the
            % slider
            if (value > app.RSlider.Limits(2))
                app.RSlider.Value = app.RSlider.Limits(2);
            elseif (value < app.RSlider.Limits(1))
                app.RSlider.Value = app.RSlider.Limits(1);
            else
                app.RSlider.Value = value;
            end
            
            % updates display and sets the edit fields to the correct
            % values
            RSliderValueChanged(app, event)

        end

        % Value changed function: ChangeThetaEditField
        function ChangeThetaEditFieldValueChanged(app, event)
            % updates the value for Theta on the slider and calls the slider's
            % function to update the image
            value = app.ChangeThetaEditField.Value;

            % Verify that the number is not outside of the range of the
            % slider
            if (value > app.ThetaSlider.Limits(2))
                app.ThetaSlider.Value = app.ThetaSlider.Limits(2);
            elseif (value < app.ThetaSlider.Limits(1))
                app.ThetaSlider.Value = app.ThetaSlider.Limits(1);
            else
                app.ThetaSlider.Value = value;
            end

            % updates display and sets the edit fields to the correct
            % values
            RSliderValueChanged(app, event)
            
        end

        % Value changed function: ChangePhiEditField
        function ChangePhiEditFieldValueChanged(app, event)
            % updates the value for Phi on the slider and calls the slider's
            % function to update the image
            value = app.ChangePhiEditField.Value;
            
            % Verify that the number is not outside of the range of the
            % slider
            if (value > app.PhiSlider.Limits(2))
                app.PhiSlider.Value = app.PhiSlider.Limits(2);
            elseif (value < app.PhiSlider.Limits(1))
                app.PhiSlider.Value = app.PhiSlider.Limits(1);
            else
                app.PhiSlider.Value = value;
            end

            % updates display and sets the edit fields to the correct
            % values
            RSliderValueChanged(app, event)
            
        end

        % Value changed function: PhiSlider, RSlider, ThetaSlider
        function RSliderValueChanged(app, event)
            %set all edit fields to the correct value
            app.ChangeREditField.Value = app.RSlider.Value;
            app.ChangeThetaEditField.Value = app.ThetaSlider.Value;
            app.ChangePhiEditField.Value = app.PhiSlider.Value;

            %change the figure due to the new values of the sliders
            updateImage(app, app.RSlider.Value, app.ThetaSlider.Value, app.PhiSlider.Value);
        end

        % Value changed function: MartixField11
        function MartixField11ValueChanged(app, event)
            value = app.MartixField11.Value;

            %%  Todo
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ControlsPanel = uipanel(app.UIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.PhiSliderLabel = uilabel(app.ControlsPanel);
            app.PhiSliderLabel.Text = 'Phi';
            app.PhiSlider = uislider(app.ControlsPanel);
            app.PhiSlider.Limits = [-6.28318530717959 6.28318530717959];
            app.PhiSlider.MajorTicks = [-6.28318530717959 -3.14159265358979 0 3.14159265358979 6.28318530717959];
            app.PhiSlider.MajorTickLabels = {'-2pi', '-pi', '0', 'pi', '2pi'};
            app.PhiSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.PhiSlider.ValueChangingFcn = createCallbackFcn(app, @PhiSliderValueChanging, true);
            app.PhiSlider.MinorTicks = [-4.71238898038469 -1.5707963267949 1.5707963267949 4.71238898038469];
            app.RSliderLabel = uilabel(app.ControlsPanel);
            app.RSliderLabel.Text = 'R';
            app.RSlider = uislider(app.ControlsPanel);
            app.RSlider.Limits = [0 0.2];
            app.RSlider.MajorTicks = [0 0.05 0.1 0.15 0.2];
            app.RSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.RSlider.ValueChangingFcn = createCallbackFcn(app, @RSliderValueChanging, true);
            app.RSlider.MinorTicks = [];
            app.ThetaSliderLabel = uilabel(app.ControlsPanel);
            app.ThetaSliderLabel.Text = 'Theta';
            app.ThetaSlider = uislider(app.ControlsPanel);
            app.ThetaSlider.Limits = [-6.28318530717959 6.28318530717959];
            app.ThetaSlider.MajorTicks = [-6.28318530717959 -3.14159265358979 0 3.14159265358979 6.28318530717959];
            app.ThetaSlider.MajorTickLabels = {'-2pi', '-pi', '0', 'pi', '2pi'};
            app.ThetaSlider.ValueChangedFcn = createCallbackFcn(app, @RSliderValueChanged, true);
            app.ThetaSlider.ValueChangingFcn = createCallbackFcn(app, @ThetaSliderValueChanging, true);
            app.ThetaSlider.MinorTicks = [-4.71238898038469 -1.5707963267949 1.5707963267949 4.71238898038469];
            app.ChangePhiEditFieldLabel = uilabel(app.ControlsPanel);
            app.ChangePhiEditFieldLabel.Text = 'Change Phi';
            app.ChangePhiEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ChangePhiEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePhiEditFieldValueChanged, true);
            app.ChangeThetaEditFieldLabel = uilabel(app.ControlsPanel);
            app.ChangeThetaEditFieldLabel.Text = 'Change Theta';
            app.ChangeThetaEditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ChangeThetaEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeThetaEditFieldValueChanged, true);
            app.ChangeREditFieldLabel = uilabel(app.ControlsPanel);
            app.ChangeREditFieldLabel.Text = 'Change R';
            app.ChangeREditField = uieditfield(app.ControlsPanel, 'numeric');
            app.ChangeREditField.ValueChangedFcn = createCallbackFcn(app, @ChangeREditFieldValueChanged, true);
            app.ManualMatrixControlsPanel = uipanel(app.UIFigure);
            app.ManualMatrixControlsPanel.Title = 'Manual Matrix Controls';
            app.MartixField21 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField31 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField41 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField41.Editable = 'off';
            app.MartixField42 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField42.Editable = 'off';
            app.MartixField11 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField11.ValueChangedFcn = createCallbackFcn(app, @MartixField11ValueChanged, true);
            app.MartixField13 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField32 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField22 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField12 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField14 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField23 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField43 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField43.Editable = 'off';
            app.MartixField24 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField34 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField33 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField44 = uieditfield(app.ManualMatrixControlsPanel, 'numeric');
            app.MartixField44.Editable = 'off';
            app.MartixField44.Value = 1;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Matrix Controls', 'Slider Controls'};
            app.Switch.Value = 'Matrix Controls';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end